classdef base_station < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        bWindowSizeEditField        matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel_2  matlab.ui.control.Label
        bBufferSizeEditField        matlab.ui.control.NumericEditField
        BufferSizeEditFieldLabel_2  matlab.ui.control.Label
        aWindowSizeEditField        matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel    matlab.ui.control.Label
        aBufferSizeEditField        matlab.ui.control.NumericEditField
        BufferSizeEditFieldLabel    matlab.ui.control.Label
        bDropDownSigLeft1           matlab.ui.control.DropDown
        bDropDownSigLeft2           matlab.ui.control.DropDown
        bDropDownSigRight1          matlab.ui.control.DropDown
        bDropDownSigRight2          matlab.ui.control.DropDown
        bPLAYButton                 matlab.ui.control.Button
        bPAUSEButton                matlab.ui.control.Button
        bRESETButton                matlab.ui.control.Button
        RESETALLUNIQUELABELSButton  matlab.ui.control.Button
        aDropDownSigRight2          matlab.ui.control.DropDown
        aDropDownSigRight1          matlab.ui.control.DropDown
        aPLAYButton                 matlab.ui.control.Button
        aPAUSEButton                matlab.ui.control.Button
        aRESETButton                matlab.ui.control.Button
        aDropDownSigLeft2           matlab.ui.control.DropDown
        aDropDownSigLeft1           matlab.ui.control.DropDown
        TextArea                    matlab.ui.control.TextArea
        xmlfileEditField            matlab.ui.control.EditField
        xmlfileEditFieldLabel       matlab.ui.control.Label
        DISCONNECTButton            matlab.ui.control.Button
        CONNECTButton               matlab.ui.control.Button
        BaudRateEditField           matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel      matlab.ui.control.Label
        COMPortEditField            matlab.ui.control.EditField
        COMPortEditFieldLabel       matlab.ui.control.Label
        aAxes                       matlab.ui.control.UIAxes
        bAxes                       matlab.ui.control.UIAxes
        StatsAxes                   matlab.ui.control.UIAxes
    end

    properties (Access = public)
        serial
        dialect
        mavlink
        timer_rx
        timer_heartbeat

        pool
        queue_to_worker
        queue_to_gui
        queue_debug
        worker

        timer_disp
        tx_bytes_accum = uint64(0);
        stats = struct( ...
            'status', 0, ...                   % reserved
            'radio', struct( ...               % latest RADIO_STATUS snapshot
                'rssi', NaN, ...
                'remrssi', NaN, ...
                'noise', NaN, ...
                'remnoise', NaN, ...
                'txbuf', NaN, ...
                'rxerrors', NaN, ...
                'fixed', NaN, ...
                'snr', NaN, ...                % computed: rssi - noise
                'rem_snr', NaN, ...            % computed: remrssi - remnoise
                'ts', NaT ...                  % timestamp of last update
            ), ...
            'uart', struct( ...
                'baud', NaN, ...        % current baudrate
                'bits_per_byte', NaN, ...% start + data + stop (+parity)
                'util_rx', NaN, ...     % %
                'util_tx', NaN, ...     % %
                'util_total', NaN, ...  % %
                'rx_speed', NaN, ...      % bytes/s
                'tx_speed', NaN, ...      % bytes/s
                'active', false ...
            ) ...
        );
        statsPlot struct   % cały „moduł” rysowania SNR

        unique_signals_received_struct struct = struct( ...
            'sigKeysMap', containers.Map('KeyType','char','ValueType','logical'), ... % unikalne FRAME_SIGNAL
            'sigList', strings(0,1), ...                                              % lista "FRAME_SIGNAL" (separator '_')
            'frameSeenMap', containers.Map('KeyType','char','ValueType','logical'), ...% szybkie „czy frame już widziany”
            'keyToPair', containers.Map('KeyType','char','ValueType','any') ...        % NEW: key -> struct('frame',f,'signal',s)
        );

        subscriptions struct = struct( ...
            'defaults', struct( ...
                'windowSec', 60, ...          % okno czasowe bufora
                'assumedMaxRateHz', 120 ...     % konserwatywne założenie: 120 Hz
            ) ...
        );

        time_sync struct = struct( ...
            'initialized', false, ...  % czy mamy offset
            't0_dev_s', 0.0, ...       % pierwsza próbka z MCU [s]
            't0_wall_s', 0.0, ...      % czas ścienny przy pierwszej próbce [s]
            'offset_s',  0.0, ...      % t_wall = t_dev + offset
            'prev_u32',  uint32(0), ...% ostatni timestamp z MCU (u32, ms)
            'wraps',     uint32(0) ... % liczba wrapów 2^32
        );

        % === NEW: dwa niezależne panele sygnałów ===
        panels struct = struct();   % panels(1) -> aAxes, panels(2) -> bAxes
        panel_timer                  % wspólny timer odświeżania paneli

    end
            
    methods (Access = private)

function gotoUpdateDropdown(app)
    curList = app.unique_signals_received_struct.sigList;
    items = [{'None'}; cellstr(curList)];
    safeSetDD(app.aDropDownSigLeft1,  items);
    safeSetDD(app.aDropDownSigLeft2,  items);
    safeSetDD(app.aDropDownSigRight1, items);
    safeSetDD(app.aDropDownSigRight2, items);
    safeSetDD(app.bDropDownSigLeft1,  items);
    safeSetDD(app.bDropDownSigLeft2,  items);
    safeSetDD(app.bDropDownSigRight1, items);
    safeSetDD(app.bDropDownSigRight2, items);
    function safeSetDD(dd, items)
        if isempty(dd) || ~isvalid(dd), return; end
        % porównaj tylko rozmiar/ostatni element (tanie i wystarcza praktycznie zawsze)
        need = numel(dd.Items) ~= numel(items);
        if ~need && ~isempty(items)
            need = ~strcmp(dd.Items{end}, items{end});
        end
        if need
            dd.Items = items;
            if isempty(dd.Value) || ~any(strcmp(dd.Value, items))
                dd.Value = 'None';
            end
        end
    end
end

function initPanels(app)
    app.panels = repmat(struct( ...
        'ax', [], ...
        'paused', false, ...
        'anchorNowPosix', 0.0, ...
        'windowSec', app.subscriptions.defaults.windowSec, ...
        'bufferSamples', 512, ...           % NEW: stała długość bufora na panel
        'frameToSlots', containers.Map('KeyType','char','ValueType','any'), ...
        'slots', [], ...
        'hLeft', gobjects(1,2), ...
        'hRight', gobjects(1,2) ...
    ), 1, 2);

    app.initOnePanel(1, app.aAxes);
    app.initOnePanel(2, app.bAxes);
end

function initOnePanel(app, pid, ax)
    P = app.panels(pid);
    P.ax = ax;
    P.paused = false;
    P.anchorNowPosix = posixtime(datetime('now'));

    % === zaciągnij startowe wartości z pól UI ===
    if pid==1
        if ~isempty(app.aWindowSizeEditField),   P.windowSec     = max(1, double(app.aWindowSizeEditField.Value)); end
        if ~isempty(app.aBufferSizeEditField),   P.bufferSamples = max(128, round(double(app.aBufferSizeEditField.Value))); end
    else
        if ~isempty(app.bWindowSizeEditField),   P.windowSec     = max(1, double(app.bWindowSizeEditField.Value)); end
        if ~isempty(app.bBufferSizeEditField),   P.bufferSamples = max(128, round(double(app.bBufferSizeEditField.Value))); end
    end

    P.frameToSlots = containers.Map('KeyType','char','ValueType','any');

    % sloty
    S = repmat(struct( ...
        'enabled', false, ...
        'key', "", ...
        'frame', "", ...
        'signal', "", ...
        'side', 'L', ...
        'ring', struct() ...
    ), 1, 4);
    S(1).side='L'; S(2).side='L'; S(3).side='R'; S(4).side='R';
    P.slots = S;

    hold(ax,'on');
    try
        disableDefaultInteractivity(ax);
        if isprop(ax,'Toolbar') && ~isempty(ax.Toolbar)
            ax.Toolbar.Visible = 'off';
        end
    catch, end

    yyaxis(ax,'left');
    P.hLeft(1)=plot(ax,NaN,NaN,'-','LineWidth',1.2,'DisplayName','L1');
    P.hLeft(2)=plot(ax,NaN,NaN,'-','LineWidth',1.2,'DisplayName','L2');
    yyaxis(ax,'right');
    P.hRight(1)=plot(ax,NaN,NaN,'-','LineWidth',1.2,'DisplayName','R1');
    P.hRight(2)=plot(ax,NaN,NaN,'-','LineWidth',1.2,'DisplayName','R2');

    ax.XLim=[-P.windowSec 0];
    ax.XGrid='on'; yyaxis(ax,'left');ax.YGrid='on'; yyaxis(ax,'right');ax.YGrid='on';

    app.panels(pid)=P;
end

function onPanelDropdownChanged(app, pid, slotIdx)
% Obsługa zmiany dropdownu dla konkretnego panela i slotu 1..4
    % Mapowanie: które dropdowny odpowiadają którym slotom
    dd = app.panelGetDropdownHandle(pid, slotIdx);
    if isempty(dd), return; end
    sel = string(dd.Value);

    P = app.panels(pid);
    if sel == "None"
        app.unsubscribePanelSlot(pid, slotIdx);
        return;
    end

    key = char(sel);
    K = app.unique_signals_received_struct.keyToPair;
    if ~isKey(K, key)
        app.unsubscribePanelSlot(pid, slotIdx);
        return;
    end
    pair = K(key);

    % Konfiguruj slot
    P.slots(slotIdx).enabled = true;
    P.slots(slotIdx).key     = sel;
    P.slots(slotIdx).frame   = string(pair.frame);
    P.slots(slotIdx).signal  = string(pair.signal);

    % ring reinit na stały rozmiar panelu
    P.slots(slotIdx).ring = app.ringInit(P.bufferSamples);

    % mapa frame->sloty
    P.frameToSlots = app.panelAddSlotToFrameMap(P.frameToSlots, char(pair.frame), slotIdx);

    app.panels(pid) = P;
end

function dd = panelGetDropdownHandle(app, pid, slotIdx)
% Zwraca uchwyt do odpowiedniego dropdownu
    dd = [];
    if pid == 1
        switch slotIdx
            case 1, dd = app.aDropDownSigLeft1;
            case 2, dd = app.aDropDownSigLeft2;
            case 3, dd = app.aDropDownSigRight1;
            case 4, dd = app.aDropDownSigRight2;
        end
    else % pid==2
        switch slotIdx
            case 1, dd = app.bDropDownSigLeft1;
            case 2, dd = app.bDropDownSigLeft2;
            case 3, dd = app.bDropDownSigRight1;
            case 4, dd = app.bDropDownSigRight2;
        end
    end
end

function unsubscribePanelSlot(app, pid, slotIdx)
    P = app.panels(pid);
    if slotIdx <= 0 || slotIdx > numel(P.slots), return; end
    % usuń z mappingu
    if P.slots(slotIdx).frame ~= ""
        P.frameToSlots = app.panelRemoveSlotFromFrameMap(P.frameToSlots, char(P.slots(slotIdx).frame), slotIdx);
    end
    % wyczyść slot
    P.slots(slotIdx).enabled = false;
    P.slots(slotIdx).key     = "";
    P.slots(slotIdx).frame   = "";
    P.slots(slotIdx).signal  = "";
    P.slots(slotIdx).ring    = struct();
    % wyczyść odpowiednią linię
    ax = P.ax;
    if P.slots(slotIdx).side == 'L'
        k = (slotIdx==1) + 1; % 1->1, 2->2
        set(P.hLeft(k), 'XData', NaN, 'YData', NaN, 'DisplayName', sprintf('L%d', k));
    else
        k = (slotIdx==3) + 1; % 3->1, 4->2
        set(P.hRight(k), 'XData', NaN, 'YData', NaN, 'DisplayName', sprintf('R%d', k));
    end
    app.panels(pid) = P;
end

function map = panelAddSlotToFrameMap(app, map, frame, slotIdx)
    if ~isKey(map, frame)
        map(frame) = slotIdx;
    else
        v = map(frame);
        if isnumeric(v)
            v = unique([v(:).' slotIdx]);
        else
            v = unique([v slotIdx]);
        end
        map(frame) = v;
    end
end

function map = panelRemoveSlotFromFrameMap(app, map, frame, slotIdx)
    if ~isKey(map, frame), return; end
    v = map(frame);
    if isnumeric(v)
        v = v(v ~= slotIdx);
        if isempty(v)
            remove(map, frame);
        else
            map(frame) = v;
        end
    end
end

function feedPanelSubscriptions(app, pid, frame_name, ts_u32_ms, sigStruct)
% Dokarmia TYLKO dany panel (pauza: nic nie robi)
    P = app.panels(pid);
    if P.paused, return; end
    frame = char(frame_name);
    if isempty(P.frameToSlots) || ~isKey(P.frameToSlots, frame)
        return;
    end
    slotIdxs = P.frameToSlots(frame);
    if isempty(slotIdxs), return; end

    % map MCU time -> wall time
    try
        t_plot = app.mapDeviceTime(ts_u32_ms);
    catch
        t_plot = posixtime(datetime('now'));
    end

    for k = 1:numel(slotIdxs)
        idx = slotIdxs(k);
        if ~P.slots(idx).enabled, continue; end
        sigName = char(P.slots(idx).signal);
        if isstruct(sigStruct) && isfield(sigStruct, sigName)
            val = sigStruct.(sigName);
            if isnumeric(val) && isscalar(val) && ~isnan(val)
                % korzystamy z istniejącego ringPush - ale potrzebuje 'app.subscriptions'
                % Hack: tymczasowo wykorzystamy istniejące API - bez ruszania starego kodu:
                % stworzymy „lokalny” push:
                R = P.slots(idx).ring;
                if ~isempty(fieldnames(R))
                    N = double(R.N);
                    h = double(R.head) + 1;
                    if h > N
                        h = 1;
                        R.full = true;
                    end
                    R.t(h) = double(t_plot);
                    R.y(h) = single(double(val));
                    R.head = uint32(h);
                    P.slots(idx).ring = R;
                end
            end
        end
    end
    app.panels(pid) = P;
end

function updateAllPanels(app)
    if isempty(app.panels), return; end 
    for pid = 1:numel(app.panels) 
        if ~app.panels(pid).paused 
            app.updatePanelPlot(pid); 
        end 
    end
end

function [x, y] = panelRingGetWindow(~, R, anchorPosix, windowSec)
    if isempty(R) || ~isfield(R,'N') || R.N==0
        x = []; y = []; return;
    end
    N = double(R.N);
    if R.head == 0 && ~R.full
        x = []; y = []; return;
    end
    if R.full
        idx = 1:N;
    else
        idx = 1:double(R.head);
    end
    t = R.t(idx); yv = double(R.y(idx));
    mask = isfinite(t) & isfinite(yv) & (t >= anchorPosix - windowSec) & (t <= anchorPosix);
    if ~any(mask), x=[]; y=[]; return; end
    t = t(mask); yv = yv(mask);
    x = t - anchorPosix;     % relatywna oś czasu [s], 0 = anchor (now albo zamrożony now)
    [x, order] = sort(x);
    y = yv(order);
end

function updatePanelPlot(app, pid)
    if isempty(app.panels) || pid<1 || pid>numel(app.panels), return; end
    P = app.panels(pid);
    ax = P.ax; if ~isgraphics(ax), return; end

    if P.paused
        nowPosix = P.anchorNowPosix;
    else
        nowPosix = posixtime(datetime('now'));
        P.anchorNowPosix = nowPosix;
    end
    win = P.windowSec;

    % ===== LEFT =====
    yyaxis(ax,'left');
    yminL = inf; ymaxL = -inf; gotL = false;
    for k=1:2
        slot = P.slots(k);
        if slot.enabled && isfield(slot.ring,'N') && slot.ring.N>0
            [x,y] = app.panelRingGetWindow(slot.ring, nowPosix, win);
            if isempty(x) || isempty(y)
                set(P.hLeft(k),'XData',NaN,'YData',NaN);
            else
                set(P.hLeft(k),'XData',x,'YData',y,'DisplayName',char(slot.key));
                % running min/max
                yl = min(y); yh = max(y);
                if isfinite(yl) && isfinite(yh)
                    yminL = min(yminL, yl); ymaxL = max(ymaxL, yh); gotL = true;
                end
            end
        else
            set(P.hLeft(k),'XData',NaN,'YData',NaN);
        end
    end
    if ~P.paused && gotL
        if yminL==ymaxL
            d = max(1,abs(yminL))*0.05;
            ylim(ax,[yminL-d, ymaxL+d]);
        else
            d = 0.05*(ymaxL - yminL);
            ylim(ax,[yminL-d, ymaxL+d]);
        end
    end

    % ===== RIGHT =====
    yyaxis(ax,'right');
    yminR = inf; ymaxR = -inf; gotR = false;
    for k=1:2
        slot = P.slots(2+k);
        if slot.enabled && isfield(slot.ring,'N') && slot.ring.N>0
            [x,y] = app.panelRingGetWindow(slot.ring, nowPosix, win);
            if isempty(x) || isempty(y)
                set(P.hRight(k),'XData',NaN,'YData',NaN);
            else
                set(P.hRight(k),'XData',x,'YData',y,'DisplayName',char(slot.key));
                yl = min(y); yh = max(y);
                if isfinite(yl) && isfinite(yh)
                    yminR = min(yminR, yl); ymaxR = max(ymaxR, yh); gotR = true;
                end
            end
        else
            set(P.hRight(k),'XData',NaN,'YData',NaN);
        end
    end
    if ~P.paused && gotR
        if yminR==ymaxR
            d = max(1,abs(yminR))*0.05;
            ylim(ax,[yminR-d, ymaxR+d]);
        else
            d = 0.05*(ymaxR - yminR);
            ylim(ax,[yminR-d, ymaxR+d]);
        end
    end

    % X-lim tylko gdy gra
    if ~P.paused
        tgt = [-win 0];
        if ~isequal(ax.XLim, tgt), ax.XLim = tgt; end
    end

    drawnow limitrate nocallbacks;
    app.panels(pid)=P;
end


function onPanelWindowChanged(app, pid, newWin)
    newWin = max(1, double(newWin));
    P = app.panels(pid);
    P.windowSec = newWin;
    % od razu odśwież XLim przy play
    if ~P.paused && isgraphics(P.ax)
        P.ax.XLim = [-newWin 0];
    end
    app.panels(pid) = P;
end

function onPanelBufferChanged(app, pid, newN)
    newN = max(32, round(double(newN)));
    P = app.panels(pid);
    if P.bufferSamples == newN
        return; % nic nie rób
    end
    P.bufferSamples = newN;
    % Reinit ringów w slotach tego panelu (czyści dane, zero realloc w runtime)
    for i=1:numel(P.slots)
        if P.slots(i).enabled
            P.slots(i).ring = app.ringInit(newN);
        end
    end
    app.panels(pid) = P;
end

function onPanelPlay(app, pid)
    P = app.panels(pid);
    P.paused = false;
    % anchor będzie aktualizowany w updatePanelPlot
    app.panels(pid) = P;
    % disp(sprintf('[panel %d] PLAY', pid));
end

function onPanelPause(app, pid)
    P = app.panels(pid);
    P.paused = true;
    P.anchorNowPosix = posixtime(datetime('now')); % zamroź czas
    app.panels(pid) = P;
    % disp(sprintf('[panel %d] PAUSE', pid));
end

function onPanelReset(app, pid)
    P = app.panels(pid);
    % wyczyść sloty/ringi
    for i=1:4
        P.slots(i).enabled = false;
        P.slots(i).key = ""; P.slots(i).frame=""; P.slots(i).signal="";
        P.slots(i).ring = struct();
    end
    % czyść linie na lewą/prawą
    ax = P.ax;
    yyaxis(ax,'left');
    for k=1:2
        set(P.hLeft(k),'XData',NaN,'YData',NaN,'DisplayName',sprintf('L%d',k));
    end
    ax.YLimMode = 'auto';   % <— ZAMIAST 'ylim(''auto'')'
    yyaxis(ax,'right');
    for k=1:2
        set(P.hRight(k),'XData',NaN,'YData',NaN,'DisplayName',sprintf('R%d',k));
    end
    ax.YLimMode = 'auto';   % <— jw.
    ax.XLim = [-P.windowSec 0];
    % mapy i dropdowny
    P.frameToSlots = containers.Map('KeyType','char','ValueType','any');
    if pid==1
        if ~isempty(app.aDropDownSigLeft1),  app.aDropDownSigLeft1.Value  = 'None'; end
        if ~isempty(app.aDropDownSigLeft2),  app.aDropDownSigLeft2.Value  = 'None'; end
        if ~isempty(app.aDropDownSigRight1), app.aDropDownSigRight1.Value = 'None'; end
        if ~isempty(app.aDropDownSigRight2), app.aDropDownSigRight2.Value = 'None'; end
    else
        if ~isempty(app.bDropDownSigLeft1),  app.bDropDownSigLeft1.Value  = 'None'; end
        if ~isempty(app.bDropDownSigLeft2),  app.bDropDownSigLeft2.Value  = 'None'; end
        if ~isempty(app.bDropDownSigRight1), app.bDropDownSigRight1.Value = 'None'; end
        if ~isempty(app.bDropDownSigRight2), app.bDropDownSigRight2.Value = 'None'; end
    end
    % unpause + reset anchor
    P.paused = false;
    P.anchorNowPosix = posixtime(datetime('now'));
    app.panels(pid) = P;
end

function onResetAllUniqueLabels(app)
% Czyści listę unikalnych kluczy i dropdowny obu paneli
    app.unique_signals_received_struct.sigKeysMap = containers.Map('KeyType','char','ValueType','logical');
    app.unique_signals_received_struct.sigList    = strings(0,1);
    app.unique_signals_received_struct.frameSeenMap = containers.Map('KeyType','char','ValueType','logical');
    app.unique_signals_received_struct.keyToPair  = containers.Map('KeyType','char','ValueType','any');
    % ustaw dropdown Items na {'None'} i Value='None'
    dds = [ ...
        app.aDropDownSigLeft1, app.aDropDownSigLeft2, app.aDropDownSigRight1, app.aDropDownSigRight2, ...
        app.bDropDownSigLeft1, app.bDropDownSigLeft2, app.bDropDownSigRight1, app.bDropDownSigRight2 ...
    ];
    for i = 1:numel(dds)
        if ~isempty(dds(i)) && isvalid(dds(i))
            dds(i).Items = {'None'};
            dds(i).Value = 'None';
        end
    end
    disp('[unique labels] cleared');
end

function clearAllPanelsRings(app)
% Czyści ring-buffery we WSZYSTKICH panelach (bez zmiany wyborów dropdown).
    if isempty(app.panels), return; end
    for pid = 1:numel(app.panels)
        P = app.panels(pid);
        for i = 1:numel(P.slots)
            if isfield(P.slots(i),'ring') && ~isempty(P.slots(i).ring)
                R = P.slots(i).ring;
                if isfield(R,'N') && R.N > 0
                    R.t(:) = NaN;
                    R.y(:) = single(NaN);
                    R.head = uint32(0);
                    R.full = false;
                    P.slots(i).ring = R;
                end
            end
        end
        app.panels(pid) = P;
    end
    disp('[clearAllPanelsRings] all panel rings cleared');
end


        function initStatsPlot(app)
            S.ax         = app.StatsAxes;
            S.minWindowS = 60;     % minimalna szerokość osi czasu w sekundach
            S.maxSamples = 600;    % <-- ILE OSTATNICH PRÓBEK PRZECHOWUJEMY (stały bufor)
                                   % np. 300 próbek przy ~1 Hz ≈ 5 min danych
            % --- bufor kołowy (prealokacja, ZERO dynamicznych alokacji w update) ---
            S.bufT     = NaN(1, S.maxSamples);   % czas względny [s], ujemny ... 0
            S.bufSNR   = NaN(1, S.maxSamples);
            S.bufRmSNR = NaN(1, S.maxSamples);
            S.head     = 0;                      % indeks ostatnio wpisanego (1..maxSamples)
            S.full     = false;                  % czy bufor już „okrążył”
            S.lastNow  = datetime('now');        % do liczenia dt między wywołaniami
            ax = S.ax; hold(ax, 'on');
            ax.YLim = [0 100];
            % Wyłącz interakcje
            try
                disableDefaultInteractivity(ax);
                ax.Toolbar.Visible = 'off';
            catch
            end
            % --- Kolorowe tło wg SNR (blade) ---
            bgRanges = [
                 0   5    [1.0 0.4 0.4];  % zły
                 5   10   [1.0 0.7 0.4];  % kiepski
                10   20   [1.0 1.0 0.4];  % ok
                20  100   [0.7 1.0 0.7];  % dobry
            ];
            S.bgPatches = gobjects(size(bgRanges,1),1);
            for i = 1:size(bgRanges,1)
                y1 = bgRanges(i,1);
                y2 = bgRanges(i,2);
                c  = bgRanges(i,3:end);
                S.bgPatches(i) = patch(ax, ...
                    'XData', [-S.minWindowS 0 0 -S.minWindowS], ...
                    'YData', [y1 y1 y2 y2], ...
                    'FaceColor', c, ...
                    'FaceAlpha', 0.2, ...
                    'EdgeColor', 'none', ...
                    'HitTest', 'off', ...
                    'PickableParts', 'none', ...
                    'HandleVisibility', 'off');  % << nie trafia do legendy
            end
            % Linie SNR
            S.hSNR    = plot(ax, NaN, NaN, '-', 'LineWidth', 1, 'DisplayName','SNR [dB]');
            S.hRemSNR = plot(ax, NaN, NaN, '-', 'LineWidth', 1, 'DisplayName','rmSNR [dB]');
            % Oś i styl
            ylabel(ax, 'SNR [dB]');
            ax.XLabel.String = '';
            ax.YLabel.String = '';
            box(ax, 'off');
            ax.XGrid = 'off'; ax.YGrid = 'off';
            ax.XLim = [-S.minWindowS 0];
            legend(ax, 'Location','northwest', 'Box','off', 'AutoUpdate','off');
            app.statsPlot = S;
        end
        function updateStatsPlot(app, SNR, rmSNR)
            S = app.statsPlot;
            if isempty(S), return; end
            % --- przesunięcie czasu względnego o -dt dla CAŁEGO bufora ---
            tnow = datetime('now');
            dt = seconds(tnow - S.lastNow);
            S.lastNow = tnow;
            % Uaktualnij tylko istniejące elementy (bez NaNów)
            if S.full
                idx = 1:S.maxSamples;
            else
                if S.head == 0
                    idx = [];   % jeszcze nic nie mamy
                else
                    idx = 1:S.head;
                end
            end
            if ~isempty(idx)
                S.bufT(idx) = S.bufT(idx) - dt;   % wszystko „płynie” w lewo o dt
            end
            % --- wstaw nową próbkę na głowę bufora ---
            S.head = S.head + 1;
            if S.head > S.maxSamples
                S.head = 1;
                S.full = true;
            end
            S.bufT(S.head)     = 0;      % „teraz” po prawej
            S.bufSNR(S.head)   = SNR;
            S.bufRmSNR(S.head) = rmSNR;
            % --- z jakiego zakresu rysujemy? ---
            % oś czasu dostosowana do rzeczywistego zasięgu czasów w buforze,
            % ale MINIMALNIE S.minWindowS
            if S.full
                allIdx = 1:S.maxSamples;
            else
                allIdx = 1:S.head;
            end
            % tylko te próbki, które mają X<=0 (czas względny) i Y nie-NaN
            valid1 = allIdx(~isnan(S.bufSNR(allIdx))   & ~isnan(S.bufT(allIdx))   & S.bufT(allIdx) <= 0);
            valid2 = allIdx(~isnan(S.bufRmSNR(allIdx)) & ~isnan(S.bufT(allIdx))   & S.bufT(allIdx) <= 0);
            % bieżący rozciąg czasowy danych
            minRelT = 0;
            if ~isempty(allIdx)
                % najmniejszy (najbardziej ujemny) czas w dostępnych próbkach
                haveT = S.bufT(allIdx);
                haveT = haveT(~isnan(haveT));
                if ~isempty(haveT)
                    minRelT = min(haveT);   % np. -133.2
                end
            end
            axisWidth = max(S.minWindowS, -minRelT);   % [s], co najmniej 60 s
            % --- aktualizacja szerokości tła do bieżącej osi czasu ---
            if isfield(S, 'bgPatches') && ~isempty(S.bgPatches)
                newX = [-axisWidth 0 0 -axisWidth];
                for k = 1:numel(S.bgPatches)
                    if isgraphics(S.bgPatches(k))
                        set(S.bgPatches(k), 'XData', newX);
                    end
                end
            end
            % klip do aktualnego okna (żeby nie rysować „dawnych” próbek)
            inwin = @(ids) ids(S.bufT(ids) >= -axisWidth & S.bufT(ids) <= 0);
            ids1 = inwin(valid1);
            ids2 = inwin(valid2);
            % musimy wyświetlić w KOLEJNOŚCI rosnącej po X
            [x1, order1] = sort(S.bufT(ids1));
            y1 = S.bufSNR(ids1); y1 = y1(order1);
            [x2, order2] = sort(S.bufT(ids2));
            y2 = S.bufRmSNR(ids2); y2 = y2(order2);
            % aktualizacja linii (bez replotów)
            set(S.hSNR,    'XData', x1, 'YData', y1);
            set(S.hRemSNR, 'XData', x2, 'YData', y2);
            % stała prawa krawędź w 0, lewa = -axisWidth (≥ 60 s)
            ax = S.ax;
            if isvalid(ax)
                ax.XLim = [-axisWidth 0];
            end
            % throttling + tańsze rysowanie
            persistent lastDrawT
            if isempty(lastDrawT), lastDrawT = tic; end
            if toc(lastDrawT) > 0.25   % rysuj max ~4 Hz niezależnie od burstów
                drawnow limitrate nocallbacks
                lastDrawT = tic;
            end
            app.statsPlot = S;
        end


        function bpb = get_bits_per_byte(app)
            % 1 start + DataBits + StopBits + (parity ? 1 : 0)
            try
                dataBits = app.serial.DataBits;      % zwykle 8
                stopBits = app.serial.StopBits;      % 1, 1.5, 2 (zaokrąglimy w górę)
                parityOn = ~strcmpi(app.serial.Parity, "none");
                bpb = 1 + double(dataBits) + ceil(double(stopBits)) + double(parityOn);
            catch
                bpb = 10; % domyślnie 8N1
            end
        end
        function refresh_uart_params(app, baud)
            app.stats.uart.baud = double(baud);
            app.stats.uart.bits_per_byte = app.get_bits_per_byte();
        end
        function writeSerial(app, data, precision)
            if nargin < 3, precision = "uint8"; end
            write(app.serial, data, precision);
            % zlicz ile bajtów poszło
            app.tx_bytes_accum = app.tx_bytes_accum + uint64(numel(typecast(cast(data, 'uint8'), 'uint8')));
        end

        function sendHeartbeat(app)
            try
                hbMsg = createmsg(app.dialect, "HEARTBEAT");
                % GCS identity and basic mode/state for MAVLink v1.0
                hbMsg.Payload.type = uint8(6);              % MAV_TYPE_GCS (6)
                hbMsg.Payload.autopilot = uint8(8);         % MAV_AUTOPILOT_INVALID (8)
                hbMsg.Payload.base_mode = uint8(0);         % No base mode flags set
                hbMsg.Payload.system_status = uint8(0);     % MAV_STATE_UNINIT (0)
                hbMsg.Payload.custom_mode = uint32(0);      % No custom mode
                % hbMsg.Payload.mavlink_version = uint8(3); % Not writable by user
                hbMsg.SystemID = uint8(255);                % Typical GCS system ID
                hbMsg.ComponentID = uint8(190);             % Typical GCS component ID
                buffer = serializemsg(app.mavlink, hbMsg);
                app.writeSerial(buffer, "uint8");
            catch e
                disp("Heartbeat send error: " + e.message)
            end
        end

        function rx(app)
            % RX read
            data = uint8([]);
            if app.serial.NumBytesAvailable > 0
                data = read(app.serial, app.serial.NumBytesAvailable, "uint8");
                data = uint8(data);
                send(app.queue_to_worker, data);
            end
        
            % Sample period = timer_rx.Period
            Ts = app.timer_rx.Period;
        
            % RX speed (bytes/s)
            rx_bytes = length(data);
            app.stats.uart.rx_speed = app.avg_speed_window(rx_bytes, Ts, "rx");
        
            % TX speed (bytes/s)
            tx_bytes = double(app.tx_bytes_accum);
            app.tx_bytes_accum = uint64(0); % reset po próbkowaniu
            app.stats.uart.tx_speed = app.avg_speed_window(tx_bytes, Ts, "tx");
        
            % Port utilization
            baud = app.stats.uart.baud;
            bpb  = app.stats.uart.bits_per_byte;
            if ~(isnan(baud) || baud <= 0)
                % bits/s użyte: bytes/s * bits/byte
                util_rx = (app.stats.uart.rx_speed * bpb) / baud;
                util_tx = (app.stats.uart.tx_speed * bpb) / baud;
                app.stats.uart.util_rx    = 100 * util_rx;
                app.stats.uart.util_tx    = 100 * util_tx;
                app.stats.uart.util_total = 100 * (util_rx + util_tx); % przy half‑duplex to i tak max~100
            else
                app.stats.uart.util_rx = NaN;
                app.stats.uart.util_tx = NaN;
                app.stats.uart.util_total = NaN;
            end
        end
        
        function speed = avg_speed_window(app, bytes, period, tag)
            % Keeps a per-tag sliding window average of byte rate.
            % 'tag' identifies separate buffers for RX and TX.
            % Uses a 1-second window split into 1/period samples.
            window_time = 1; % [s]
            samples = max(1, round(window_time / period));
            persistent map;
            if isempty(map), map = containers.Map(); end
            if ~isKey(map, tag)
                map(tag) = struct( ...
                    'buffer', zeros(1, samples), ...
                    'index', 0, ...
                    'count', 0, ...
                    'sum_bytes', 0, ...
                    'samples', samples);
            end
            st = map(tag);
            % Realloc if period changed => samples changed
            if st.samples ~= samples
                st.buffer = zeros(1, samples);
                st.index = 0; st.count = 0; st.sum_bytes = 0; st.samples = samples;
            end
            st.index = mod(st.index, samples) + 1;
            st.sum_bytes = st.sum_bytes - st.buffer(st.index) + bytes;
            st.buffer(st.index) = bytes;
            st.count = min(st.count + 1, samples);
            map(tag) = st;
            speed = st.sum_bytes / (st.count * period); % bytes/s
        end


% --- MAIN DISPLAY UPDATE ---
function disp_update_stats(app)
    % lokalny helper tylko na potrzeby tego bloku:
    function out = ternNum(val, fmt)
        if isnan(val)
            out = "--";
        else
            out = sprintf(fmt, val);
        end
    end
    function out = ternNumFlag(flag, onText, offText)
        if flag
            out = onText;
        else
            out = offText;
        end
    end
    function s = staleSuffix(ts)
        if ~isnat(ts)
            age = seconds(datetime('now') - ts);
            s = sprintf('(age: %.1fs)', age);
        else
            s = '';
        end
    end
    % --- Uart utilization ---
    u = app.stats.uart;
    lines_util = {
        sprintf("Serial port %s", ternNumFlag(u.active, 'active', 'inactive'))
        sprintf(" RX speed:\t%s",     ternNum(u.rx_speed,   "%.1f B/s"))
        sprintf(" TX speed:\t%s",     ternNum(u.tx_speed,   "%.1f B/s"))
        sprintf(" RX load:\t%s",      ternNum(u.util_rx,    "%.1f%%"))
        sprintf(" TX load:\t%s",      ternNum(u.util_tx,    "%.1f%%"))
        sprintf(" Total load:\t%s",   ternNum(u.util_total, "%.1f%%"))
        sprintf(" Baud rate:\t%s",    ternNum(u.baud,       "%g"))
        sprintf(" Bits per byte: %s", ternNum(u.bits_per_byte, "%g"))
    };
    % --- RADIO STATUS ---
    r = app.stats.radio;
    lines_radio = {
        sprintf("RADIO LINK %s", staleSuffix(r.ts))
        sprintf(" RSSI (local):    %s", ternNum(r.rssi,  "%.0f dBm"))
        sprintf(" RSSI (remote):   %s", ternNum(r.remrssi,"%.0f dBm"))
        sprintf(" Noise (local):   %s", ternNum(r.noise,  "%.0f dBm"))
        sprintf(" Noise (remote):  %s", ternNum(r.remnoise,"%.0f dBm"))
        sprintf(" SNR (local):     %s", ternNum(r.snr,    "%.0f dB"))
        sprintf(" SNR (remote):    %s", ternNum(r.rem_snr,"%.0f dB"))
        sprintf(" TX buffer free:  %s", ternNum(r.txbuf,  "%.0f%%"))
        sprintf(" RX errors (cum): %s", ternNum(r.rxerrors,"%.0f"))
        sprintf(" Fixed (FEC, cum):%s", ternNum(r.fixed,  "%.0f"))
    };
    % --- Compose and show ---
    app.TextArea.Value = [
        cellstr(lines_util);
        cellstr(lines_radio)
    ];
end
function resetStats(app)
    % --- RADIO ---
    app.stats.radio.rssi     = NaN;
    app.stats.radio.remrssi  = NaN;
    app.stats.radio.noise    = NaN;
    app.stats.radio.remnoise = NaN;
    app.stats.radio.txbuf    = NaN;
    app.stats.radio.rxerrors = NaN;
    app.stats.radio.fixed    = NaN;
    app.stats.radio.snr      = NaN;
    app.stats.radio.rem_snr  = NaN;
    % --- UART ---
    app.stats.uart.baud          = NaN;
    app.stats.uart.bits_per_byte = NaN;
    app.stats.uart.util_rx       = NaN;
    app.stats.uart.util_tx       = NaN;
    app.stats.uart.util_total    = NaN;
    app.stats.uart.rx_speed      = NaN;
    app.stats.uart.tx_speed      = NaN;
end

function added = updateUniqueSignals(app, frame_name, signal_names)
% Rejestruj FRAME tylko raz. Dodaj klucze FRAME_SIGNAL TYLKO przy pierwszym
% pojawieniu się tego frame'a. Nie oznaczaj "frame seen", jeśli nie doszły żadne sygnały.

    added = false;
    if isempty(frame_name)
        return; % bez nazwy frame — nie robimy nic
    end

    f = char(frame_name);

    % Early-exit: jeśli frame już był, nic nie rób (Twoje wymaganie)
    frameSeen = app.unique_signals_received_struct.frameSeenMap;
    if isKey(frameSeen, f)
        return;
    end

    % Normalizacja listy sygnałów
    if isstring(signal_names)
        sigs = cellstr(signal_names);
    elseif iscell(signal_names)
        sigs = signal_names;
    else
        try
            sigs = fieldnames(signal_names);
        catch
            sigs = {};
        end
    end

    % Zbuduj i dopisz klucze FRAME_SIGNAL
    if ~isempty(sigs)
        mapRef  = app.unique_signals_received_struct.sigKeysMap;
        listRef = app.unique_signals_received_struct.sigList;

        for k = 1:numel(sigs)
            sk = sigs{k};
            if isempty(sk), continue; end
            key = [f '_' sk];             % FRAME_SIGNAL
            if ~isKey(mapRef, key)
                mapRef(key) = true;
                listRef(end+1,1) = string(key);
                app.unique_signals_received_struct.keyToPair(key) = struct('frame', f, 'signal', sk);
                added = true;
            end
        end

        % Zapisz z powrotem tylko jeśli coś dodaliśmy
        if added
            app.unique_signals_received_struct.sigKeysMap = mapRef;
            app.unique_signals_received_struct.sigList    = listRef;
        end
    end

    % Oznacz frame jako widziany TYLKO gdy coś faktycznie dodaliśmy
    if added
        frameSeen(f) = true;
        app.unique_signals_received_struct.frameSeenMap = frameSeen;
        app.gotoUpdateDropdown();   % odśwież UI raz na „pierwsze spotkanie” frame'a
    end
end

function R = ringInit(app, N)
    N = max(32, round(double(N)));       % stały rozmiar, zero realloc
    R = struct( ...
        't',   NaN(1, N), ...            % double
        'y',   nan(1, N, 'single'), ...  % single
        'head', uint32(0), ...
        'full', false, ...
        'N',    uint32(N) ...
    );
end

function t_plot = mapDeviceTime(app, ts_u32_ms)
% Mapuje timestamp z MCU (u32 ms since boot) na czas ścienny [s].
% Zwraca t_plot w sekundach POSIX (double), gotowy do rysowania.
    % Bezpieczne rzutowanie
    ts = uint32(ts_u32_ms);
    now_s = posixtime(datetime('now'));
    if ~app.time_sync.initialized
        % Pierwsza próbka -> inicjalizacja offsetu
        dev_s = double(ts) / 1000.0;
        app.time_sync.t0_dev_s = dev_s;
        app.time_sync.t0_wall_s = now_s;
        app.time_sync.offset_s  = now_s - dev_s;
        app.time_sync.prev_u32  = ts;
        app.time_sync.wraps     = uint32(0);
        app.time_sync.initialized = true;
        % disp(sprintf('[time_sync] init: dev=%.3fs wall=%.3fs offset=%.3fs', ...
        %     dev_s, now_s, app.time_sync.offset_s));
        t_plot = dev_s + app.time_sync.offset_s;
        return;
    end
    prev = double(app.time_sync.prev_u32);
    curr = double(ts);
    if curr < prev
        % Rozróżnij wrap vs reset
        if (prev - curr) > 2^31
            % WRAP 2^32
            app.time_sync.wraps = app.time_sync.wraps + 1;
            % disp(sprintf('[time_sync] wrap detected (wraps=%u)', app.time_sync.wraps));
        else
            % RESET URZĄDZENIA -> re-sync
            % disp('[time_sync] device reset detected -> re-sync + clear rings');
            app.time_sync.initialized = false;  % ponowna inicjalizacja przy tej próbce
            app.clearAllPanelsRings();
            t_plot = app.mapDeviceTime(ts);     % rekurencyjnie zainicjuj i policz
            return;
        end
    end
    app.time_sync.prev_u32 = ts;
    % Rozwinięty czas MCU w sekundach
    dev_ext_ms = curr + double(app.time_sync.wraps) * 4294967296.0; % 2^32
    dev_s = dev_ext_ms / 1000.0;
    % Stałe przesunięcie (bez kompensacji dryfu)
    t_plot = dev_s + app.time_sync.offset_s;
end
              
        function onMsgs(app, msgs)
            % Handle parsed messages arriving from the worker.
            function v = toDbl(s, name)
                if isfield(s, name) && ~isempty(s.(name))
                    v = double(s.(name));
                else
                    v = NaN;
                end
            end
            for i = 1:numel(msgs)
                s = msgs{i};
                if ~isstruct(s), continue; end
                switch s.msgid
                    case 0 % HEARTBEAT
                        % Example fields: s.type, s.base_mode, s.system_status
                    case 109 % RADIO_STATUS
                        % Store full snapshot and compute SNRs
                        app.stats.radio.rssi     = (toDbl(s, 'rssi') / 1.9 ) - 127; % dBm
                        app.stats.radio.remrssi  = (toDbl(s, 'remrssi') / 1.9 ) - 127; % dBm
                        app.stats.radio.noise    = (toDbl(s, 'noise') / 1.9 ) - 127; % dBm
                        app.stats.radio.remnoise = (toDbl(s, 'remnoise') / 1.9 ) - 127; % dBm
                        app.stats.radio.txbuf    = toDbl(s, 'txbuf');
                        app.stats.radio.rxerrors = toDbl(s, 'rxerrors');
                        app.stats.radio.fixed    = toDbl(s, 'fixed');
                        if ~isnan(app.stats.radio.rssi) && ~isnan(app.stats.radio.noise)
                            app.stats.radio.snr = app.stats.radio.rssi - app.stats.radio.noise;
                        else
                            app.stats.radio.snr = NaN;
                        end
                        if ~isnan(app.stats.radio.remrssi) && ~isnan(app.stats.radio.remnoise)
                            app.stats.radio.rem_snr = app.stats.radio.remrssi - app.stats.radio.remnoise;
                        else
                            app.stats.radio.rem_snr = NaN;
                        end
                        app.stats.radio.ts = datetime('now');
                        app.updateStatsPlot(app.stats.radio.snr, app.stats.radio.rem_snr);
                    case 200 % GENERIC_CAN_FRAME
                        % Extract the signal_name and frame_names
                        frame_name = s.decoded.frame;
                        signal_names = fieldnames(s.decoded.signals);
                        app.updateUniqueSignals(frame_name, signal_names);

                        % --- NEW: nakarm oba panele niezależnie (pauza respektowana przy feed) ---
                        app.feedPanelSubscriptions(1, frame_name, s.timestamp, s.decoded.signals);
                        app.feedPanelSubscriptions(2, frame_name, s.timestamp, s.decoded.signals);

                    case 201 % DEBUG_FRAME
                        
                    otherwise
                        
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.timer_disp = timer('ExecutionMode', 'fixedSpacing', ...
                                      'Period', 1/10, ... % 10 hz
                                      'TimerFcn', @(~,~) app.disp_update_stats());
            start(app.timer_disp);

            app.initStatsPlot();

            % panele (aAxes i bAxes)
            app.initPanels();
        
            % podpięcie callbacków a/*
            app.aDropDownSigLeft1.ValueChangedFcn  = @(~,~) app.onPanelDropdownChanged(1, 1); % panel 1, slot 1 (LEFT)
            app.aDropDownSigLeft2.ValueChangedFcn  = @(~,~) app.onPanelDropdownChanged(1, 2); % panel 1, slot 2 (LEFT)
            app.aDropDownSigRight1.ValueChangedFcn = @(~,~) app.onPanelDropdownChanged(1, 3); % panel 1, slot 3 (RIGHT)
            app.aDropDownSigRight2.ValueChangedFcn = @(~,~) app.onPanelDropdownChanged(1, 4); % panel 1, slot 4 (RIGHT)
        
            app.aPLAYButton.ButtonPushedFcn  = @(~,~) app.onPanelPlay(1);
            app.aPAUSEButton.ButtonPushedFcn = @(~,~) app.onPanelPause(1);
            app.aRESETButton.ButtonPushedFcn = @(~,~) app.onPanelReset(1);

            app.aWindowSizeEditField.ValueChangedFcn = @(~,~) app.onPanelWindowChanged(1, app.aWindowSizeEditField.Value);
            app.aBufferSizeEditField.ValueChangedFcn = @(~,~) app.onPanelBufferChanged(1, app.aBufferSizeEditField.Value);
        
            % podpięcie callbacków b/*
            app.bDropDownSigLeft1.ValueChangedFcn  = @(~,~) app.onPanelDropdownChanged(2, 1);
            app.bDropDownSigLeft2.ValueChangedFcn  = @(~,~) app.onPanelDropdownChanged(2, 2);
            app.bDropDownSigRight1.ValueChangedFcn = @(~,~) app.onPanelDropdownChanged(2, 3);
            app.bDropDownSigRight2.ValueChangedFcn = @(~,~) app.onPanelDropdownChanged(2, 4);
        
            app.bPLAYButton.ButtonPushedFcn  = @(~,~) app.onPanelPlay(2);
            app.bPAUSEButton.ButtonPushedFcn = @(~,~) app.onPanelPause(2);
            app.bRESETButton.ButtonPushedFcn = @(~,~) app.onPanelReset(2);

            app.bWindowSizeEditField.ValueChangedFcn = @(~,~) app.onPanelWindowChanged(2, app.bWindowSizeEditField.Value);
            app.bBufferSizeEditField.ValueChangedFcn = @(~,~) app.onPanelBufferChanged(2, app.bBufferSizeEditField.Value);
        
            % RESET ALL UNIQUE LABELS
            app.RESETALLUNIQUELABELSButton.ButtonPushedFcn = @(~,~) app.onResetAllUniqueLabels();
        
            % panel_timer do odświeżania obu paneli
            try
                if ~isempty(app.panel_timer) && isvalid(app.panel_timer)
                    stop(app.panel_timer); delete(app.panel_timer);
                end
                app.panel_timer = timer('ExecutionMode','fixedRate', ...
                                        'BusyMode','drop', ...
                                        'Period', 1, ...        % ~1 Hz odświeżania
                                        'TimerFcn', @(~,~) app.updateAllPanels());
                start(app.panel_timer);
                disp('Panels plot timer started');
            catch e
                uialert(app.UIFigure, ['Error starting panels plot timer: ', e.message], 'Timer Error');
            end
        end

        % Button pushed function: CONNECTButton
        function connectSerial(app, event)
            clc
            port = char(app.COMPortEditField.Value);
            baud = double(app.BaudRateEditField.Value);
            xmlPath = char(app.xmlfileEditField.Value);
            
            % 1) Dialect + mavlink
            try
                app.dialect = mavlinkdialect(xmlPath, 1);
                app.mavlink = mavlinkio(app.dialect);
                disp(['Dialect ', xmlPath, ' loaded']);
            catch e
                app.mavlink = [];
                uialert(app.UIFigure, ['Error loading dialect: ', e.message], 'MAVLink Error');
                return;
            end
    
            % 2) Serial
            try
                app.serial = serialport(port, baud);
                app.refresh_uart_params(baud);
                app.stats.uart.active = true;
                disp(['Connected to ', port, ' at ', num2str(baud), ' baud']);
            catch e
                app.stats.uart.active = false;
                uialert(app.UIFigure, ['Error connecting: ', e.message], 'Connection Error');
                return; % No port -> do not start nothig further
            end
    
            % 3) Pool/queues/worker
            try
                disp("Starting the thread worker...")
                if ~isempty(gcp('nocreate'))
                    delete(gcp('nocreate'))
                end
                app.pool = parpool('Processes',1);
                app.queue_to_worker = parallel.pool.PollableDataQueue(Destination="any");
                app.queue_to_gui = parallel.pool.DataQueue;
                app.queue_debug = parallel.pool.DataQueue;
                afterEach(app.queue_to_gui, @(msgs) app.onMsgs(msgs));
                afterEach(app.queue_debug, @(data) disp("@@" + string(data)));
                app.worker = parfeval(app.pool, @worker, 0, app.queue_to_worker, app.queue_to_gui, app.queue_debug);
                disp('Started a thread worker for heavy tasks');
            catch e
                uialert(app.UIFigure, ['Error starting a worker: ', e.message], 'Thread Error');
                return;
            end
    
            % 4) RX timer
            try
                app.timer_rx = timer('ExecutionMode', 'fixedSpacing', ...
                          'Period', 0.25, ...
                          'TimerFcn', @(~,~) app.rx());
                start(app.timer_rx);
            catch e
                uialert(app.UIFigure, ['Error with a timer: ', e.message], 'Timer Error');
            end
        
            % 5) Heartbeat timer
            try
                app.timer_heartbeat = timer('ExecutionMode', 'fixedSpacing', ...
                                             'Period', 1.0, ...
                                             'TimerFcn', @(~,~) app.sendHeartbeat());
                start(app.timer_heartbeat);
                disp("Heartbeat timer started");
            catch e
                uialert(app.UIFigure, ['Error starting heartbeat timer: ', e.message], 'Timer Error');
            end
        end

        % Button pushed function: DISCONNECTButton
        function disconnectSerial(app, event)
            % 1) Timers
            try
                stop(app.timer_rx)
                delete(app.timer_rx)
            catch e
                uialert(app.UIFigure, ['Error stoping timer: ', e.message], 'Thread Error');
            end
            try
                stop(app.timer_heartbeat);
                delete(app.timer_heartbeat);
                disp("Heartbeat timer stopped");
            catch e
                uialert(app.UIFigure, ['Error stoping timer: ', e.message], 'Thread Error');
            end
      
            % 2) Serial
            try
                if ~isempty(app.serial) && isvalid(app.serial)
                    delete(app.serial);
                end
                app.stats.uart.active = false;
                app.resetStats()
                disp(['Serial port ', char(app.COMPortEditField.Value), ' disconnected']);
            catch e
                app.stats.uart.active = false;
                app.resetStats()
                uialert(app.UIFigure, ['Error disconnecting: ', e.message], 'Connection Error');
            end
            
            % 3) Workers / pool
            try
                if ~isempty(app.worker) && isvalid(app.worker)
                    try cancel(app.worker); catch, end 
                    delete(app.worker);
                    app.worker = [];
                end
            catch e
                uialert(app.UIFigure, ['Error stopping worker: ', e.message], 'Thread Error');
            end
            try
                if ~isempty(gcp('nocreate'))
                    delete(gcp('nocreate'))
                end
                if ~isempty(app.pool)
                    delete(app.pool);
                    app.pool = [];
                end
                disp('Stopped a thread pool');
            catch e
                uialert(app.UIFigure, ['Error deleting pool: ', e.message], 'Thread Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StatsAxes = uiaxes(app.UIFigure);
            app.bAxes = uiaxes(app.UIFigure);
            app.aAxes = uiaxes(app.UIFigure);
            app.COMPortEditFieldLabel = uilabel(app.UIFigure);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.UIFigure, 'text');
            app.COMPortEditField.Placeholder = 'COM';
            app.COMPortEditField.Value = 'COM17';
            app.BaudRateEditFieldLabel = uilabel(app.UIFigure);
            app.BaudRateEditFieldLabel.Text = 'BaudRate';
            app.BaudRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaudRateEditField.Limits = [0 Inf];
            app.BaudRateEditField.ValueDisplayFormat = '%d';
            app.BaudRateEditField.Value = 115200;
            app.CONNECTButton = uibutton(app.UIFigure, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @connectSerial, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.DISCONNECTButton = uibutton(app.UIFigure, 'push');
            app.DISCONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @disconnectSerial, true);
            app.DISCONNECTButton.Text = 'DISCONNECT';
            app.xmlfileEditFieldLabel = uilabel(app.UIFigure);
            app.xmlfileEditFieldLabel.Text = '.xml file';
            app.xmlfileEditField = uieditfield(app.UIFigure, 'text');
            app.xmlfileEditField.Placeholder = './mavmc.xml';
            app.xmlfileEditField.Value = '/mavmc.xml';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Placeholder = 'Connection stats';
            app.aDropDownSigLeft1 = uidropdown(app.UIFigure);
            app.aDropDownSigLeft1.Items = {'None'};
            app.aDropDownSigLeft1.Value = 'None';
            app.aDropDownSigLeft2 = uidropdown(app.UIFigure);
            app.aDropDownSigLeft2.Items = {'None'};
            app.aDropDownSigLeft2.Value = 'None';
            app.aRESETButton = uibutton(app.UIFigure, 'push');
            app.aRESETButton.Text = 'RESET';
            app.aPAUSEButton = uibutton(app.UIFigure, 'push');
            app.aPAUSEButton.Text = 'PAUSE';
            app.aPLAYButton = uibutton(app.UIFigure, 'push');
            app.aPLAYButton.Text = 'PLAY';
            app.aDropDownSigRight1 = uidropdown(app.UIFigure);
            app.aDropDownSigRight1.Items = {'None'};
            app.aDropDownSigRight1.Value = 'None';
            app.aDropDownSigRight2 = uidropdown(app.UIFigure);
            app.aDropDownSigRight2.Items = {'None'};
            app.aDropDownSigRight2.Value = 'None';
            app.RESETALLUNIQUELABELSButton = uibutton(app.UIFigure, 'push');
            app.RESETALLUNIQUELABELSButton.Text = 'RESET ALL UNIQUE LABELS';
            app.bRESETButton = uibutton(app.UIFigure, 'push');
            app.bRESETButton.Text = 'RESET';
            app.bPAUSEButton = uibutton(app.UIFigure, 'push');
            app.bPAUSEButton.Text = 'PAUSE';
            app.bPLAYButton = uibutton(app.UIFigure, 'push');
            app.bPLAYButton.Text = 'PLAY';
            app.bDropDownSigRight2 = uidropdown(app.UIFigure);
            app.bDropDownSigRight2.Items = {'None'};
            app.bDropDownSigRight2.Value = 'None';
            app.bDropDownSigRight1 = uidropdown(app.UIFigure);
            app.bDropDownSigRight1.Items = {'None'};
            app.bDropDownSigRight1.Value = 'None';
            app.bDropDownSigLeft2 = uidropdown(app.UIFigure);
            app.bDropDownSigLeft2.Items = {'None'};
            app.bDropDownSigLeft2.Value = 'None';
            app.bDropDownSigLeft1 = uidropdown(app.UIFigure);
            app.bDropDownSigLeft1.Items = {'None'};
            app.bDropDownSigLeft1.Value = 'None';
            app.BufferSizeEditFieldLabel = uilabel(app.UIFigure);
            app.BufferSizeEditFieldLabel.Text = 'BufferSize';
            app.aBufferSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.aBufferSizeEditField.Limits = [128 4096];
            app.aBufferSizeEditField.ValueDisplayFormat = '%d';
            app.aBufferSizeEditField.Value = 512;
            app.WindowSizeEditFieldLabel = uilabel(app.UIFigure);
            app.WindowSizeEditFieldLabel.Text = 'WindowSize';
            app.aWindowSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.aWindowSizeEditField.Limits = [0 Inf];
            app.aWindowSizeEditField.ValueDisplayFormat = '%d';
            app.aWindowSizeEditField.Value = 120;
            app.BufferSizeEditFieldLabel_2 = uilabel(app.UIFigure);
            app.BufferSizeEditFieldLabel_2.Text = 'BufferSize';
            app.bBufferSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.bBufferSizeEditField.Limits = [128 4096];
            app.bBufferSizeEditField.ValueDisplayFormat = '%d';
            app.bBufferSizeEditField.Value = 512;
            app.WindowSizeEditFieldLabel_2 = uilabel(app.UIFigure);
            app.WindowSizeEditFieldLabel_2.Text = 'WindowSize';
            app.bWindowSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.bWindowSizeEditField.Limits = [0 Inf];
            app.bWindowSizeEditField.ValueDisplayFormat = '%d';
            app.bWindowSizeEditField.Value = 120;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = base_station

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end